classdef FoueBar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        generalTab                      matlab.ui.container.Tab
        InputsvariablePanel             matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        degLabel_10                     matlab.ui.control.Label
        Theta1EditField                 matlab.ui.control.NumericEditField
        Theta1EditFieldLabel_2          matlab.ui.control.Label
        Theta1EditField_3               matlab.ui.control.NumericEditField
        R5EditField                     matlab.ui.control.NumericEditField
        R5EditFieldLabel                matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        rads2Label_4                    matlab.ui.control.Label
        radsLabel_6                     matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        DropDown_theta                  matlab.ui.control.DropDown
        DropDown_omega                  matlab.ui.control.DropDown
        omegaEditField                  matlab.ui.control.NumericEditField
        DropDown_alpha                  matlab.ui.control.DropDown
        alternateconfigurationCheckBox  matlab.ui.control.CheckBox
        alphaEditField                  matlab.ui.control.NumericEditField
        ThetaEditField                  matlab.ui.control.NumericEditField
        Theta5EditField                 matlab.ui.control.NumericEditField
        Theta5EditFieldLabel            matlab.ui.control.Label
        R4EditField                     matlab.ui.control.NumericEditField
        R4EditFieldLabel                matlab.ui.control.Label
        R3EditField                     matlab.ui.control.NumericEditField
        R3EditFieldLabel                matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        R1EditFieldLabel                matlab.ui.control.Label
        outputPanel                     matlab.ui.container.Panel
        accelerationsPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ms2Label_6                      matlab.ui.control.Label
        ms2Label_5                      matlab.ui.control.Label
        ms2Label_4                      matlab.ui.control.Label
        ms2Label_3                      matlab.ui.control.Label
        ms2Label_2                      matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        rads2Label_7                    matlab.ui.control.Label
        rads2Label_6                    matlab.ui.control.Label
        rads2Label_5                    matlab.ui.control.Label
        Label_Alpha2                    matlab.ui.control.Label
        Alpha2Label                     matlab.ui.control.Label
        Label_anB                       matlab.ui.control.Label
        Label_atB                       matlab.ui.control.Label
        anBLabel                        matlab.ui.control.Label
        atBLabel                        matlab.ui.control.Label
        Label_anAB                      matlab.ui.control.Label
        Label_atAB                      matlab.ui.control.Label
        anABLabel                       matlab.ui.control.Label
        atABLabel                       matlab.ui.control.Label
        Label_anA                       matlab.ui.control.Label
        anALabel                        matlab.ui.control.Label
        Label_atA                       matlab.ui.control.Label
        atALabel                        matlab.ui.control.Label
        Label_Alpha4                    matlab.ui.control.Label
        Alpha4Label                     matlab.ui.control.Label
        Label_Alpha3                    matlab.ui.control.Label
        Alpha3Label                     matlab.ui.control.Label
        vlocitiesPanel                  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        radsLabel_11                    matlab.ui.control.Label
        radsLabel_10                    matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        radsLabel_9                     matlab.ui.control.Label
        radsLabel_8                     matlab.ui.control.Label
        radsLabel_7                     matlab.ui.control.Label
        Omega2Label                     matlab.ui.control.Label
        Label_Omega2                    matlab.ui.control.Label
        Label_Omega4                    matlab.ui.control.Label
        Label_Omega3                    matlab.ui.control.Label
        Omega4Label                     matlab.ui.control.Label
        Omega3Label                     matlab.ui.control.Label
        Label_VB                        matlab.ui.control.Label
        VBLabel                         matlab.ui.control.Label
        Label_VAB                       matlab.ui.control.Label
        Label_VA                        matlab.ui.control.Label
        VABLabel                        matlab.ui.control.Label
        VALabel                         matlab.ui.control.Label
        positionsPanel                  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        degLabel_8                      matlab.ui.control.Label
        degLabel_7                      matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        Label_Theta2                    matlab.ui.control.Label
        Theta2Label                     matlab.ui.control.Label
        Label_Theta4                    matlab.ui.control.Label
        Label_Theta3                    matlab.ui.control.Label
        Theta4Label                     matlab.ui.control.Label
        Theta3Label                     matlab.ui.control.Label
        plotDropDown                    matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        analysismotionTab               matlab.ui.container.Tab
        degLabel_12                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Theta2Label_2                   matlab.ui.control.Label
        plotDropDown_2                  matlab.ui.control.DropDown
        Slider                          matlab.ui.control.Slider
        InputsvariablePanel_2           matlab.ui.container.Panel
        alpha2EditField                 matlab.ui.control.NumericEditField
        alpha2EditFieldLabel            matlab.ui.control.Label
        omega2EditField                 matlab.ui.control.NumericEditField
        omega2EditFieldLabel            matlab.ui.control.Label
        radsLabel_13                    matlab.ui.control.Label
        radsLabel_12                    matlab.ui.control.Label
        Theta5EditField_2               matlab.ui.control.NumericEditField
        Theta5EditField_2Label          matlab.ui.control.Label
        degLabel_11                     matlab.ui.control.Label
        R5EditField_2                   matlab.ui.control.NumericEditField
        R5EditField_2Label              matlab.ui.control.Label
        mLabel_10                       matlab.ui.control.Label
        calculateButton_fast            matlab.ui.control.Button
        degLabel_9                      matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        Theta1EditField_2               matlab.ui.control.NumericEditField
        Theta1EditField_2Label          matlab.ui.control.Label
        R4EditField_2                   matlab.ui.control.NumericEditField
        R4EditField_2Label              matlab.ui.control.Label
        R3EditField_2                   matlab.ui.control.NumericEditField
        R3EditField_2Label              matlab.ui.control.Label
        R2EditField_2                   matlab.ui.control.NumericEditField
        R2EditField_2Label              matlab.ui.control.Label
        R1EditField_2                   matlab.ui.control.NumericEditField
        R1EditField_2Label              matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        aboutusTab                      matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        status_solve_1
        Ts_1
        Omegas_1
        Alphas_1
        Ps_1
        Vs_1
        As_1
        
        status_solve_s
        Ts_s
        Omegas_s
        Alphas_s
        Ps_s
        Vs_s
        As_s
        
        %status_solve
        %alternate_configuration = 0
        %R1
        %R2
        %R3
        %R4
        
        %T1
        %T2
        %T3
        %T4
        
        %p1
        %p2
        %p3
        %p4
        
        %va
        %vab
        %vb
        
        %Aat
        %Aan
        %Aabt
        %Aabn
        %Abt
        %Abn
        
        %omega2
        %omega3
        %omega4
        
        %alpha2
        %alpha3
        %alpha4
    end
    
    methods (Access = private)


        function calculate(app)
            app.CalculateButton.Enable = 0;
            app.CalculateButton.Text = sprintf("Waiting ...");
            drawnow
            Rs = [app.R1EditField.Value, app.R2EditField.Value, app.R3EditField.Value, app.R4EditField.Value, app.R5EditField.Value];
            T1 = app.Theta1EditField.Value;
            T5 = app.Theta5EditField.Value;
            TG = app.ThetaEditField.Value;
            TG_type = app.DropDown_theta.Value;
            OmegaG = app.omegaEditField.Value;
            OmegaG_type = app.DropDown_omega.Value;
            AlphaG = app.alphaEditField.Value;
            AlphaG_type = app.DropDown_alpha.Value;
            AC = app.alternateconfigurationCheckBox.Value;
            [app.status_solve_1, app.Ts_1, app.Ps_1, app.Vs_1, app.As_1, app.Omegas_1, app.Alphas_1] = app.solve_params(Rs, T1, T5, TG, TG_type, AC, OmegaG, OmegaG_type, AlphaG, AlphaG_type);
            update_gui(app)
            if app.status_solve_1 == 9000
                color_calculate(app, 0)
            else
                color_calculate(app, 1) 
            end
            app.CalculateButton.Enable = 1;
            app.CalculateButton.Text = sprintf("Calculate");
        end  
        
        function [] = calculate_fast(app)
            app.calculateButton_fast.Enable = 0;
            app.calculateButton_fast.Text = sprintf("Waiting ...");
            drawnow
            Rs = [app.R1EditField_2.Value, app.R2EditField_2.Value, app.R3EditField_2.Value, app.R4EditField_2.Value, app.R5EditField_2.Value];
            T1 = app.Theta1EditField_2.Value;
            T5 = app.Theta5EditField_2.Value;
            omega2 = app.omega2EditField.Value;
            alpha2 = app.alpha2EditField.Value;
            app.status_solve_s = [];
            app.Ts_s = [];
            app.Omegas_s = [];
            app.Alphas_s = [];
            app.Ps_s = [];
            app.Vs_s = [];
            app.As_s = [];
            for T2 = 0.0:1:360.0
                [status_solve, Ts, Omegas, Alphas, Ps, Vs, As] = app.solve_params_fast(Rs, T1, T2, T5, omega2, alpha2);
                app.status_solve_s = [app.status_solve_s, status_solve];
                app.Ts_s = [app.Ts_s; Ts];
                app.Omegas_s = [app.Omegas_s; Omegas];
                app.Alphas_s = [app.Alphas_s; Alphas];
%                 if status_solve == 9000
%                     a = 1;
%                 end
                app.Ps_s(:,:,T2+1) = Ps;
                app.Vs_s(:,:,T2+1) = Vs;
                app.As_s(:,:,T2+1) = As;
            end
            app.calculateButton_fast.Enable = 1;
            app.calculateButton_fast.Text = sprintf("Calculate");
            app.update_gui_fast(int32(app.Slider.Value))
            app.color_calculate_fast(0)
        end
        
        function update_gui_fast(app, T2)
            cla(app.UIAxes2)
            
            switch app.status_solve_s(T2+1)
                case 9001
                    app.Label.FontColor= "#ff0000";
                    return
                otherwise
                    app.Label.FontColor = "#c2c2c2";
            end
            

            switch app.plotDropDown_2.Value
                case 'position'
                    line(app.UIAxes2, [app.Ps_s(1,1,T2+1), app.Ps_s(2,1,T2+1)],[app.Ps_s(1,2,T2+1),app.Ps_s(2,2,T2+1)],'Color','red','LineWidth', 3)
                    line(app.UIAxes2, [app.Ps_s(2,1,T2+1), app.Ps_s(3,1,T2+1)],[app.Ps_s(2,2,T2+1),app.Ps_s(3,2,T2+1)],'Color','green','LineWidth', 3)
                    line(app.UIAxes2, [app.Ps_s(3,1,T2+1), app.Ps_s(4,1,T2+1)],[app.Ps_s(3,2,T2+1),app.Ps_s(4,2,T2+1)],'Color','blue','LineWidth', 3)
                    line(app.UIAxes2, [app.Ps_s(2,1,T2+1), app.Ps_s(5,1,T2+1)],[app.Ps_s(2,2,T2+1),app.Ps_s(5,2,T2+1)],'Color','#FF7595','LineWidth', 3)
                    line(app.UIAxes2, [app.Ps_s(5,1,T2+1), app.Ps_s(3,1,T2+1)],[app.Ps_s(5,2,T2+1),app.Ps_s(3,2,T2+1)],'Color','#FF7595','LineWidth', 3)
                    %axis(app.UIAxes, 'tight');
                    axis(app.UIAxes2, 'equal');
                    legend(app.UIAxes2,{'\color{red} R2','\color{green} R3','\color{blue} R4', '\color[rgb]{1,0.4588235294117647,0.5843137254901961} R5'});
                case 'vlocity'
                    %annotation()
                    hold(app.UIAxes2, 'on');

                    %quiver(app.UIAxes2, 1,2, 3, 4, 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes2, 0,0, app.Vs_s(1,1,T2+1), app.Vs_s(1,2,T2+1), 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.Vs_s(1,1,T2+1), app.Vs_s(1,2,T2+1), app.Vs_s(2,1,T2+1), app.Vs_s(2,2,T2+1), 0, 'Color', 'green', 'LineWidth', 3);
                    quiver(app.UIAxes2, 0,0, app.Vs_s(3,1,T2+1), app.Vs_s(3,2,T2+1), 0, 'Color', 'blue', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.Vs_s(1,1,T2+1), app.Vs_s(1,2,T2+1), app.Vs_s(4,1,T2+1), app.Vs_s(4,2,T2+1), 0, 'Color', 'white', 'LineWidth', 3);
                    legend(app.UIAxes2,{'\color{red}VA','\color{green}Vab','\color{blue}Vb','\color{white}Vac'});
                    axis(app.UIAxes2, 'equal');
                case 'acceleration'
                    %annotation()
                    hold(app.UIAxes2, 'on');

                    quiver(app.UIAxes2, 0,0, app.As_s(1,1,T2+1), app.As_s(1,2,T2+1), 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.As_s(1,1,T2+1), app.As_s(1,2,T2+1), app.As_s(1,3,T2+1), app.As_s(1,4,T2+1) , 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.As_s(1,1,T2+1)+app.As_s(1,3,T2+1), app.As_s(1,2,T2+1)+app.As_s(1,4,T2+1), app.As_s(2,1,T2+1), app.As_s(2,2,T2+1), 0, 'Color', 'green', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.As_s(1,1,T2+1)+app.As_s(2,1,T2+1)+app.As_s(1,3,T2+1), app.As_s(1,2,T2+1)+app.As_s(2,2,T2+1)+app.As_s(1,4,T2+1), app.As_s(2,3,T2+1), app.As_s(2,4,T2+1), 0, 'Color', 'green', 'LineWidth', 3);
                    quiver(app.UIAxes2, 0, 0, app.As_s(3,1,T2+1), app.As_s(3,2,T2+1), 0, 'Color', 'blue', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.As_s(3,1,T2+1), app.As_s(3,2,T2+1), app.As_s(3,3,T2+1), app.As_s(3,4,T2+1), 0, 'Color', 'blue', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.As_s(1,1,T2+1)+app.As_s(1,3,T2+1), app.As_s(1,2,T2+1)+app.As_s(1,4,T2+1), app.As_s(4,1,T2+1), app.As_s(4,2,T2+1), 0, 'Color', 'white', 'LineWidth', 3);
                    quiver(app.UIAxes2, app.As_s(1,1,T2+1)+app.As_s(4,1,T2+1)+app.As_s(1,3,T2+1), app.As_s(1,2,T2+1)+app.As_s(4,2,T2+1)+app.As_s(1,4,T2+1), app.As_s(4,3,T2+1), app.As_s(4,4,T2+1), 0, 'Color', 'white', 'LineWidth', 3);
                    hold(app.UIAxes2, 'off')
                    legend(app.UIAxes2,{'\color{red} Aan', '\color{red} Aat' ,'\color{green} Aabn' ,'\color{green} Aabt', '\color{blue} Abn', '\color{blue} Abt', '\color{white} Aacn', '\color{white} Aact'});
                    axis(app.UIAxes2, 'equal');
            end


        end
        
        function [status_solve, Ts, Omegas, Alphas, Ps, Vs, As] = solve_params_fast(app, Rs, T1, T2, T5, omega2, alpha2)
            Omegas = [0,0,0,0];
            Alphas = [0,0,0,0];
            Ps = [0,0;0,0;0,0;0,0;0,0];
            Vs = [0,0;0,0;0,0;0,0];
            As = [0,0,0,0;0,0,0,0;0,0,0,0;0,0,0,0];
            Ts = [T1,T2, 0.0,0.0,T5];
            status_solve = 9000;
            P2 = Rs(2)*[cosd(T2),sind(T2)];
            P4 = Rs(1)*[cosd(T1),sind(T1)];
            X24 = vecnorm(double(P4-P2));
            Alpha = acosd((X24^2 + Rs(3)^2 - Rs(4)^2) / (2 * X24 * Rs(3)));
            Betta = acosd((X24^2 + Rs(1)^2 - Rs(2)^2) / (2 * X24 * Rs(1)));
            if Betta ~= real(Betta) || Alpha ~= real(Alpha)
                status_solve = 9001; %input value is not true
                return
            end
            if T2 < 180
                T3 = Alpha - (Betta - T1);
            else
                T3 = Alpha + (Betta + T1);
            end
            P3 = P2 + Rs(3)*[cosd(T3),sind(T3)];
            P43 = P3 - P4;
            T4 = atan2d(P43(2),P43(1));
            omega4 = (-Rs(3)*cosd(T3)*Rs(2)*omega2*sind(T2)/(Rs(3)*sind(T3)) + Rs(2)*omega2*cosd(T2) ) / ( Rs(4)*cosd(T4)-Rs(3)*cosd(T3)*Rs(4)*sind(T4)/(Rs(3)*sind(T3)));
            omega3 = (Rs(4)*omega4*sind(T4)-Rs(2)*omega2*sind(T2)) / (Rs(3)*sind(T3));
            
            A = [+Rs(4)*cosd(T4), -Rs(3)*cosd(T3);-Rs(4)*sind(T4), Rs(3)*sind(T3)];
            B = [Rs(4)*omega4^2*sind(T4)-Rs(3)*omega3^2*sind(T3)+Rs(2)*cosd(T2)*alpha2-Rs(2)*omega2^2*sind(T2); 
                Rs(4)*omega4^2*cosd(T4)-Rs(3)*omega3^2*cosd(T3)-Rs(2)*sind(T2)*alpha2-Rs(2)*omega2^2*cosd(T2)];
            
            alpha3 = ( B(1)/A(1,1) - B(2)/A(2,1) ) / ( A(1,2)/A(1,1) - A(2,2)/A(2,1) );
            alpha4 = (B(1)- A(1,2)*alpha3) / A(1,1);
            Ts = [T1, T2, T3, T4, T5+T3];
            Omegas = [0.0, omega2, omega3, omega4];
            Alphas = [0.0, alpha2, alpha3, alpha4];
            Ps = app.calc_pos_point(Rs, Ts);
            Vs = app.calc_vlocity(Rs, Ts, Omegas);
            As = app.calc_acceleration(Rs, Ts, Omegas, Alphas);
            
        end
        
        function [status_solve, Ts, Ps, Vs, As, Omegas, Alphas] = solve_params(app, Rs, T1, T5, TG, TG_type, AC, OmegaG, OmegaG_type, AlphaG, AlphaG_type)
            
            [Ts, status_solve] = position_solve(app, Rs, T1, T5, TG, TG_type, AC);
            Omegas = [];
            Alphas = [];
            Ps = [];
            Vs = [];
            As = [];
            if status_solve ~= 9000
                return
            end
            Omegas = vlocity_solve(app, Rs, Ts, OmegaG, OmegaG_type);
            Alphas = acceleration_solve(app, Rs, Ts, Omegas, AlphaG, AlphaG_type);
            Ps = app.calc_pos_point( Rs, Ts);
            Vs = calc_vlocity(app, Rs, Ts, Omegas);
            As = calc_acceleration(app, Rs, Ts, Omegas, Alphas);
            
        end
        
        function update_gui(app)
            cla(app.UIAxes)
            switch app.status_solve_1
                case 9001
                    color = "#FF0266";
                    app.change_color_position(color)
                    return
                otherwise
                    color = "#252525";
                    app.change_color_position(color)
            end
            
            switch app.plotDropDown.Value
                case 'position'
                    
                    line(app.UIAxes, [app.Ps_1(1,1), app.Ps_1(2,1)],[app.Ps_1(1,2),app.Ps_1(2,2)],'Color','red','LineWidth', 3)
                    line(app.UIAxes, [app.Ps_1(2,1), app.Ps_1(3,1)],[app.Ps_1(2,2),app.Ps_1(3,2)],'Color','green','LineWidth', 3)
                    line(app.UIAxes, [app.Ps_1(3,1), app.Ps_1(4,1)],[app.Ps_1(3,2),app.Ps_1(4,2)],'Color','blue','LineWidth', 3)
                    line(app.UIAxes, [app.Ps_1(2,1), app.Ps_1(5,1)],[app.Ps_1(2,2),app.Ps_1(5,2)],'Color','#FF7595','LineWidth', 3)
                    line(app.UIAxes, [app.Ps_1(5,1), app.Ps_1(3,1)],[app.Ps_1(5,2),app.Ps_1(3,2)],'Color','#FF7595','LineWidth', 3)
                    %axis(app.UIAxes, 'tight');
                    axis(app.UIAxes, 'equal');
                    legend(app.UIAxes,{'\color{red} R2','\color{green} R3','\color{blue} R4', '\color[rgb]{1,0.4588235294117647,0.5843137254901961} R5'});
                case 'vlocity'
                    %annotation()
                    hold(app.UIAxes, 'on');

                    %quiver(app.UIAxes, 1,2, 3, 4, 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes, 0,0, app.Vs_1(1,1), app.Vs_1(1,2), 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes, app.Vs_1(1,1), app.Vs_1(1,2), app.Vs_1(2,1), app.Vs_1(2,2), 0, 'Color', 'green', 'LineWidth', 3);
                    quiver(app.UIAxes, 0,0, app.Vs_1(3,1), app.Vs_1(3,2), 0, 'Color', 'blue', 'LineWidth', 3);
                    quiver(app.UIAxes, app.Vs_1(1,1), app.Vs_1(1,2), app.Vs_1(4,1), app.Vs_1(4,2), 0, 'Color', 'white', 'LineWidth', 3);
                    %hold(app.UIAxes, 'off')
                    %annotation(app.UIAxes, 'arrow', [app.Ps(1), arrowEnd(1)], [app.Ps(2), arrowEnd(2)]);
                    %line(app.UIAxes, [app.results{"Vs"}(1,1), app.results{"Vs"}(2,1)],[app.results{"Vs"}(1,2),app.results{"Vs"}(2,2)],'Color','red','LineWidth', 3)
                    %line(app.UIAxes, [app.results{"Vs"}(2,1), app.results{"Vs"}(3,1)],[app.results{"Vs"}(2,2),app.results{"Vs"}(3,2)],'Color','green','LineWidth', 3)
                    %line(app.UIAxes, [app.results{"Vs"}(3,1), app.results{"Vs"}(4,1)],[app.results{"Vs"}(3,2),app.results{"Vs"}(4,2)],'Color','blue','LineWidth', 3)
                    %line(app.UIAxes, [app.results{"Vs"}(4,1), app.results{"Vs"}(1,1)],[app.results{"Vs"}(4,2),app.results{"Vs"}(1,2)],'Color','black','LineWidth', 3)
                    %axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'\color{red}VA','\color{green}Vab','\color{blue}Vb','\color{white}Vac'});
                    axis(app.UIAxes, 'equal');
                case 'acceleration'
                    %annotation()
                    hold(app.UIAxes, 'on');

                    quiver(app.UIAxes, 0,0, app.As_1(1,1), app.As_1(1,2), 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes, app.As_1(1,1), app.As_1(1,2), app.As_1(1,3), app.As_1(1,4) , 0, 'Color', 'red', 'LineWidth', 3);
                    quiver(app.UIAxes, app.As_1(1,1)+app.As_1(1,3), app.As_1(1,2)+app.As_1(1,4), app.As_1(2,1), app.As_1(2,2), 0, 'Color', 'green', 'LineWidth', 3);
                    quiver(app.UIAxes, app.As_1(1,1)+app.As_1(2,1)+app.As_1(1,3), app.As_1(1,2)+app.As_1(2,2)+app.As_1(1,4), app.As_1(2,3), app.As_1(2,4), 0, 'Color', 'green', 'LineWidth', 3);
                    quiver(app.UIAxes, 0, 0, app.As_1(3,1), app.As_1(3,2), 0, 'Color', 'blue', 'LineWidth', 3);
                    quiver(app.UIAxes, app.As_1(3,1), app.As_1(3,2), app.As_1(3,3), app.As_1(3,4), 0, 'Color', 'blue', 'LineWidth', 3);
                    quiver(app.UIAxes, app.As_1(1,1)+app.As_1(1,3), app.As_1(1,2)+app.As_1(1,4), app.As_1(4,1), app.As_1(4,2), 0, 'Color', 'white', 'LineWidth', 3);
                    quiver(app.UIAxes, app.As_1(1,1)+app.As_1(4,1)+app.As_1(1,3), app.As_1(1,2)+app.As_1(4,2)+app.As_1(1,4), app.As_1(4,3), app.As_1(4,4), 0, 'Color', 'white', 'LineWidth', 3);
                    hold(app.UIAxes, 'off')
                    %annotation(app.UIAxes, 'arrow', [app.Ps(1), arrowEnd(1)], [app.Ps(2), arrowEnd(2)]);
                    %line(app.UIAxes, [app.results{"Vs"}(1,1), app.results{"Vs"}(2,1)],[app.results{"Vs"}(1,2),app.results{"Vs"}(2,2)],'Color','red','LineWidth', 3)
                    %line(app.UIAxes, [app.results{"Vs"}(2,1), app.results{"Vs"}(3,1)],[app.results{"Vs"}(2,2),app.results{"Vs"}(3,2)],'Color','green','LineWidth', 3)
                    %line(app.UIAxes, [app.results{"Vs"}(3,1), app.results{"Vs"}(4,1)],[app.results{"Vs"}(3,2),app.results{"Vs"}(4,2)],'Color','blue','LineWidth', 3)
                    %line(app.UIAxes, [app.results{"Vs"}(4,1), app.results{"Vs"}(1,1)],[app.results{"Vs"}(4,2),app.results{"Vs"}(1,2)],'Color','black','LineWidth', 3)
                    %axis(app.UIAxes, 'tight');
                    legend(app.UIAxes,{'\color{red} Aan', '\color{red} Aat' ,'\color{green} Aabn' ,'\color{green} Aabt', '\color{blue} Abn', '\color{blue} Abt', '\color{white} Aacn', '\color{white} Aact'});
                    axis(app.UIAxes, 'equal');
            end
            app.Label_Theta2.Text = sprintf("= %.2f", double(app.Ts_1(2)));
            app.Label_Theta3.Text = sprintf("= %.2f", double(app.Ts_1(3)));
            app.Label_Theta4.Text = sprintf("= %.2f", double(app.Ts_1(4)));
            app.Label_Omega2.Text = sprintf("= %.2f", double(app.Omegas_1(2)));
            app.Label_Omega3.Text = sprintf("= %.2f", double(app.Omegas_1(3)));
            app.Label_Omega4.Text = sprintf("= %.2f", double(app.Omegas_1(4)));
            app.Label_Alpha2.Text = sprintf("= %.2f", double(app.Alphas_1(2)));
            app.Label_Alpha3.Text = sprintf("= %.2f", double(app.Alphas_1(3)));
            app.Label_Alpha4.Text = sprintf("= %.2f", double(app.Alphas_1(4)));
            app.Label_VA.Text =     sprintf("= %.2f", vecnorm(double(app.Vs_1(1,:))));
            app.Label_VAB.Text =    sprintf("= %.2f", vecnorm(double(app.Vs_1(2,:))));
            app.Label_VB.Text =     sprintf("= %.2f", vecnorm(double(app.Vs_1(3,:))));
            app.Label_atA.Text =    sprintf("= %.2f", vecnorm(double(app.As_1(1,1:2))));
            app.Label_anA.Text =    sprintf("= %.2f", vecnorm(double(app.As_1(1,3:4))));
            app.Label_atAB.Text =   sprintf("= %.2f", vecnorm(double(app.As_1(2,1:2))));
            app.Label_anAB.Text =   sprintf("= %.2f", vecnorm(double(app.As_1(2,3:4))));
            app.Label_atB.Text =    sprintf("= %.2f", vecnorm(double(app.As_1(3,1:2))));
            app.Label_anB.Text =    sprintf("= %.2f", vecnorm(double(app.As_1(3,3:4))));
            %app.UIAxes.XLim = app.UIAxes.XLim + [(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))*(-.05),(app.UIAxes.XLim(2)-app.UIAxes.XLim(1))*(.05)]
            %app.UIAxes.YLim = app.UIAxes.YLim + [(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))*(-.05),(app.UIAxes.YLim(2)-app.UIAxes.YLim(1))*(.05)]
            
        end
        
        function [Ts, status_solve] = position_solve(~, Rs, T1, T5, TG, TG_type, AC)
            status_solve = 9000;
            syms sT3
            syms sT4
            syms sT2
            eq1 = -Rs(1)*1i*sind(T1) + Rs(2)*1i*sind(sT2) + Rs(3)*1i*sind(sT3) - Rs(4)*1i*sind(sT4) == 0;
            eq2 = -Rs(1)*cosd(T1) + Rs(2)*cosd(sT2) + Rs(3)*cosd(sT3) - Rs(4)*cosd(sT4) == 0;
     
            switch TG_type%app.DropDown_theta.Value
                case "Theta 2"
                    eq3 = sT2 == TG;
                case "Theta 3"
                    eq3 = sT3 == TG;
                case "Theta 4"
                    eq3 = sT4 == TG;
                otherwise
            end
            
            [t4, t3, t2] = solve([eq1, eq2, eq3], [sT4,sT3, sT2]);
            T2 = t2(AC+1);
            T3 = t3(AC+1);
            T4 = t4(AC+1);
            if T3 ~= real(T3) || T4 ~= real(T4) || T2 ~= real(T2)
                status_solve = 9001; %input value is not true
            end
            Ts = [T1, T2, T3, T4, T5+T3];
        end
        
        function Omegas = vlocity_solve(~, Rs, Ts, OmegaG, OmegaG_type)
            syms somega2;
            syms somega3;
            syms somega4;
            eq4 = Rs(2)*somega2*1i*cosd(Ts(2)) + Rs(3)*somega3*1i*cosd(Ts(3)) - Rs(4)*somega4*1i*cosd(Ts(4)) == 0;
            eq5 = -Rs(2)*somega2*sind(Ts(2)) - Rs(3)*somega3*sind(Ts(3)) + Rs(4)*somega4*sind(Ts(4)) == 0;
            
            switch OmegaG_type
                case "Omega 2"
                    eq6 = somega2 == OmegaG;
                case "Omega 3"
                    eq6 = somega3 == OmegaG;
                case "Omega 4"
                    eq6 = somega4 == OmegaG;
                otherwise
            end
            
            [omega3, omega4, omega2] = solve([eq4, eq5, eq6], [somega3, somega4, somega2]);
            Omegas = [0.0, omega2, omega3, omega4];
        end
        
        function Alphas = acceleration_solve(~, Rs, Ts, omegas, AlphaG, AlphaG_type)
            syms salpha4;
            syms salpha3;
            syms salpha2;
            eq7 = -Rs(2)*omegas(2)^2*1i*sind(Ts(2)) - Rs(3)*omegas(3)^2*1i*sind(Ts(3)) + Rs(4)*omegas(4)^2*1i*sind(Ts(4)) + Rs(2)*salpha2*1i*cosd(Ts(2)) + Rs(3)*salpha3*1i*cosd(Ts(3)) - Rs(4)*salpha4*1i*cosd(Ts(4)) == 0;
            eq8 = -Rs(2)*omegas(2)^2*cosd(Ts(2)) - Rs(3)*omegas(3)^2*cosd(Ts(3)) + Rs(4)*omegas(4)^2*cosd(Ts(4)) - Rs(2)*salpha2*sind(Ts(2)) - Rs(3)*salpha3*sind(Ts(3)) + Rs(4)*salpha4*sind(Ts(4)) == 0;
                        
            switch AlphaG_type
                case "Alpha 2"
                    eq9 = salpha2 == AlphaG;
                case "Alpha 3"
                    eq9 = salpha3 == AlphaG;
                case "Alpha 4"
                    eq9 = salpha4 == AlphaG;
                otherwise
            end
            
            [alpha3, alpha4, alpha2] = solve([eq7, eq8, eq9], [salpha3, salpha4, salpha2]);
            Alphas = [0.0, alpha2, alpha3, alpha4];
        end

        function Ps = calc_pos_point(~, Rs, Ts)
            p1 = [0.0,0.0];%error
            p2 = [Rs(2)*cosd(Ts(2)), Rs(2)*sind(Ts(2))];
            p3 = p2 + [Rs(3)*cosd(Ts(3)), Rs(3)*sind(Ts(3))];
            p4 = [Rs(1)*cosd(Ts(1)), Rs(1)*sind(Ts(1))];
            p5 = p2 + [Rs(5)*cosd(Ts(5)), Rs(5)*sind(Ts(5))];
            Ps = [p1; p2; p3; p4; p5];
        end
        
        function Vs = calc_vlocity(~, Rs, Ts, omegas)
            
            va = [omegas(2)*Rs(2)*cosd(90+Ts(2)), omegas(2)*Rs(2)*sind(90+Ts(2))];
            vac = [omegas(3)*Rs(5)*cosd(90+Ts(5)), omegas(3)*Rs(5)*sind(90+Ts(5))];
            vab = [omegas(3)*Rs(3)*cosd(90+Ts(3)), omegas(3)*Rs(3)*sind(90+Ts(3))];
            vb = [omegas(4)*Rs(4)*cosd(90+Ts(4)), omegas(4)*Rs(4)*sind(90+Ts(4))];
            Vs = [va; vab; vb; vac];
        end
        
        function As = calc_acceleration(~, Rs, Ts, omegas, alphas)
            
            Aan = Rs(2)*omegas(2)^2*[cosd(180+Ts(2)), sind(180+Ts(2))];
            Aat = Rs(2)*alphas(2)*[cosd(90+Ts(2)), sind(90+Ts(2))];
            Aabn = Rs(3)*omegas(3)^2*[cosd(180+Ts(3)), sind(180+Ts(3))];
            Aabt = Rs(3)*alphas(3)*[cosd(90+Ts(3)), sind(90+Ts(3))];
            Aacn = Rs(5)*omegas(3)^2*[cosd(180+Ts(5)), sind(180+Ts(5))];
            Aact = Rs(5)*alphas(3)*[cosd(90+Ts(5)), sind(90+Ts(5))];
            Abn = Rs(4)*omegas(4)^2*[cosd(Ts(4)+180), sind(Ts(4)+180)];
            Abt = Rs(4)*alphas(4)*[cosd(90+Ts(4)), sind(90+Ts(4))];
            As = [Aan, Aat; Aabn, Aabt; Abn, Abt; Aacn, Aact];
        end
        
    
        function color_calculate(app, need_calculate)
            if need_calculate == 1
                app.CalculateButton.BackgroundColor = "#5e5d5e";
%             elseif need_calculate == 2
%                 app.CalculateButton.BackgroundColor = "#FF0266";
            else
                app.CalculateButton.BackgroundColor = "#1eab11";
            end
        end
        
        function color_calculate_fast(app, need_calculate)
            if need_calculate == 1
                app.calculateButton_fast.BackgroundColor = "#5e5d5e";
%             elseif need_calculate == 2
%                 app.calculateButton_fast.BackgroundColor = "#FF0266";
            else
                app.calculateButton_fast.BackgroundColor = "#1eab11";
            end
        end
        
        function change_color_position(app, color)
            
            app.R1EditField.BackgroundColor = color;
            app.R2EditField.BackgroundColor = color;
            app.R3EditField.BackgroundColor = color;
            app.R4EditField.BackgroundColor = color;
            app.Theta5EditField.BackgroundColor = color;
            app.ThetaEditField.BackgroundColor = color;
            app.omegaEditField.BackgroundColor = color;
            app.alphaEditField.BackgroundColor = color;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            calculate(app)
            app.calculate_fast()
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            app.update_gui()
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculate(app)
        end

        % Button pushed function: calculateButton_fast
        function calculateButton_fastPushed(app, event)
            app.calculate_fast()
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %app.update_gui_fast(int32(app.Slider.Value))
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.update_gui_fast(int32(changingValue))
            app.Label.Text =    sprintf("%.1f", int32(changingValue));
        end

        % Value changed function: plotDropDown_2
        function plotDropDown_2ValueChanged(app, event)
            %value = app.plotDropDown_2.Value;
            app.update_gui_fast(int32(app.Slider.Value))
        end

        % Value changed function: R1EditField
        function R1EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: R2EditField
        function R2EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: R3EditField
        function R3EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: R4EditField
        function R4EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: R5EditField
        function R5EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: Theta5EditField
        function Theta5EditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: omegaEditField
        function omegaEditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: DropDown_theta
        function DropDown_thetaValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: DropDown_omega
        function DropDown_omegaValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: DropDown_alpha
        function DropDown_alphaValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: alternateconfigurationCheckBox
        function alternateconfigurationCheckBoxValueChanged(app, event)
            app.color_calculate(1)
        end

        % Value changed function: R1EditField_2
        function R1EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: R2EditField_2
        function R2EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: R3EditField_2
        function R3EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: R4EditField_2
        function R4EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: R5EditField_2
        function R5EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: Theta1EditField_2
        function Theta1EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: Theta5EditField_2
        function Theta5EditField_2ValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: omega2EditField
        function omega2EditFieldValueChanged(app, event)
            app.color_calculate_fast(1)
        end

        % Value changed function: alpha2EditField
        function alpha2EditFieldValueChanged(app, event)
            app.color_calculate_fast(1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.generalTab = uitab(app.TabGroup);
            app.generalTab.Title = 'general';
            app.UIAxes = uiaxes(app.generalTab);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.plotDropDown = uidropdown(app.generalTab);
            app.plotDropDown.Items = {'position', 'vlocity', 'acceleration'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Value = 'position';
            app.outputPanel = uipanel(app.generalTab);
            app.outputPanel.Title = 'output';
            app.positionsPanel = uipanel(app.outputPanel);
            app.positionsPanel.Title = 'positions';
            app.GridLayout = uigridlayout(app.positionsPanel);
            app.Theta3Label = uilabel(app.GridLayout);
            app.Theta3Label.Text = 'Theta 3';
            app.Theta4Label = uilabel(app.GridLayout);
            app.Theta4Label.Text = 'Theta 4';
            app.Label_Theta3 = uilabel(app.GridLayout);
            app.Label_Theta3.Text = '';
            app.Label_Theta4 = uilabel(app.GridLayout);
            app.Label_Theta4.Text = '';
            app.Theta2Label = uilabel(app.GridLayout);
            app.Theta2Label.Text = 'Theta 2';
            app.Label_Theta2 = uilabel(app.GridLayout);
            app.Label_Theta2.Text = '';
            app.degLabel_6 = uilabel(app.GridLayout);
            app.degLabel_6.Text = 'deg';
            app.degLabel_7 = uilabel(app.GridLayout);
            app.degLabel_7.Text = 'deg';
            app.degLabel_8 = uilabel(app.GridLayout);
            app.degLabel_8.Text = 'deg';
            app.vlocitiesPanel = uipanel(app.outputPanel);
            app.vlocitiesPanel.Title = 'vlocities';
            app.GridLayout2 = uigridlayout(app.vlocitiesPanel);
            app.VALabel = uilabel(app.GridLayout2);
            app.VALabel.Text = 'V A';
            app.VABLabel = uilabel(app.GridLayout2);
            app.VABLabel.Text = 'V AB';
            app.Label_VA = uilabel(app.GridLayout2);
            app.Label_VA.Text = '';
            app.Label_VAB = uilabel(app.GridLayout2);
            app.Label_VAB.Text = '';
            app.VBLabel = uilabel(app.GridLayout2);
            app.VBLabel.Text = 'V B';
            app.Label_VB = uilabel(app.GridLayout2);
            app.Label_VB.Text = '';
            app.Omega3Label = uilabel(app.GridLayout2);
            app.Omega3Label.Text = 'Omega 3';
            app.Omega4Label = uilabel(app.GridLayout2);
            app.Omega4Label.Text = 'Omega 4';
            app.Label_Omega3 = uilabel(app.GridLayout2);
            app.Label_Omega3.Text = '';
            app.Label_Omega4 = uilabel(app.GridLayout2);
            app.Label_Omega4.Text = '';
            app.Label_Omega2 = uilabel(app.GridLayout2);
            app.Label_Omega2.Text = '';
            app.Omega2Label = uilabel(app.GridLayout2);
            app.Omega2Label.Text = 'Omega 2';
            app.radsLabel_7 = uilabel(app.GridLayout2);
            app.radsLabel_7.Text = 'rad/s';
            app.radsLabel_8 = uilabel(app.GridLayout2);
            app.radsLabel_8.Text = 'rad/s';
            app.radsLabel_9 = uilabel(app.GridLayout2);
            app.radsLabel_9.Text = 'rad/s';
            app.msLabel = uilabel(app.GridLayout2);
            app.msLabel.Text = 'm/s';
            app.radsLabel_10 = uilabel(app.GridLayout2);
            app.radsLabel_10.Text = 'rad/s';
            app.radsLabel_11 = uilabel(app.GridLayout2);
            app.radsLabel_11.Text = 'rad/s';
            app.accelerationsPanel = uipanel(app.outputPanel);
            app.accelerationsPanel.Title = 'accelerations';
            app.GridLayout3 = uigridlayout(app.accelerationsPanel);
            app.Alpha3Label = uilabel(app.GridLayout3);
            app.Alpha3Label.Text = 'Alpha 3';
            app.Label_Alpha3 = uilabel(app.GridLayout3);
            app.Label_Alpha3.Text = '';
            app.Alpha4Label = uilabel(app.GridLayout3);
            app.Alpha4Label.Text = 'Alpha 4';
            app.Label_Alpha4 = uilabel(app.GridLayout3);
            app.Label_Alpha4.Text = '';
            app.atALabel = uilabel(app.GridLayout3);
            app.atALabel.Text = 'at A';
            app.Label_atA = uilabel(app.GridLayout3);
            app.Label_atA.Text = '';
            app.anALabel = uilabel(app.GridLayout3);
            app.anALabel.Text = 'an A';
            app.Label_anA = uilabel(app.GridLayout3);
            app.Label_anA.Text = '';
            app.atABLabel = uilabel(app.GridLayout3);
            app.atABLabel.Text = 'at AB';
            app.anABLabel = uilabel(app.GridLayout3);
            app.anABLabel.Text = 'an AB';
            app.Label_atAB = uilabel(app.GridLayout3);
            app.Label_atAB.Text = '';
            app.Label_anAB = uilabel(app.GridLayout3);
            app.Label_anAB.Text = '';
            app.atBLabel = uilabel(app.GridLayout3);
            app.atBLabel.Text = 'at B';
            app.anBLabel = uilabel(app.GridLayout3);
            app.anBLabel.Text = 'an B';
            app.Label_atB = uilabel(app.GridLayout3);
            app.Label_atB.Text = '';
            app.Label_anB = uilabel(app.GridLayout3);
            app.Label_anB.Text = '';
            app.Alpha2Label = uilabel(app.GridLayout3);
            app.Alpha2Label.Text = 'Alpha 2';
            app.Label_Alpha2 = uilabel(app.GridLayout3);
            app.Label_Alpha2.Text = '';
            app.rads2Label_5 = uilabel(app.GridLayout3);
            app.rads2Label_5.Text = 'rad/s^2';
            app.rads2Label_6 = uilabel(app.GridLayout3);
            app.rads2Label_6.Text = 'rad/s^2';
            app.rads2Label_7 = uilabel(app.GridLayout3);
            app.rads2Label_7.Text = 'rad/s^2';
            app.ms2Label = uilabel(app.GridLayout3);
            app.ms2Label.Text = 'm/s^2';
            app.ms2Label_2 = uilabel(app.GridLayout3);
            app.ms2Label_2.Text = 'm/s^2';
            app.ms2Label_3 = uilabel(app.GridLayout3);
            app.ms2Label_3.Text = 'm/s^2';
            app.ms2Label_4 = uilabel(app.GridLayout3);
            app.ms2Label_4.Text = 'm/s^2';
            app.ms2Label_5 = uilabel(app.GridLayout3);
            app.ms2Label_5.Text = 'm/s^2';
            app.ms2Label_6 = uilabel(app.GridLayout3);
            app.ms2Label_6.Text = 'm/s^2';
            app.InputsvariablePanel = uipanel(app.generalTab);
            app.InputsvariablePanel.Title = 'Inputs variable';
            app.R1EditFieldLabel = uilabel(app.InputsvariablePanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.R1EditField.ValueChangedFcn = createCallbackFcn(app, @R1EditFieldValueChanged, true);
            app.R1EditField.Value = 1;
            app.R2EditFieldLabel = uilabel(app.InputsvariablePanel);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.R2EditField.ValueChangedFcn = createCallbackFcn(app, @R2EditFieldValueChanged, true);
            app.R2EditField.Value = 1;
            app.R3EditFieldLabel = uilabel(app.InputsvariablePanel);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.R3EditField.ValueChangedFcn = createCallbackFcn(app, @R3EditFieldValueChanged, true);
            app.R3EditField.Value = 0.5;
            app.R4EditFieldLabel = uilabel(app.InputsvariablePanel);
            app.R4EditFieldLabel.Text = 'R4';
            app.R4EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.R4EditField.ValueChangedFcn = createCallbackFcn(app, @R4EditFieldValueChanged, true);
            app.R4EditField.Value = 1;
            app.Theta5EditFieldLabel = uilabel(app.InputsvariablePanel);
            app.Theta5EditFieldLabel.Text = 'Theta5';
            app.Theta5EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.Theta5EditField.ValueChangedFcn = createCallbackFcn(app, @Theta5EditFieldValueChanged, true);
            app.Theta5EditField.Value = 30;
            app.ThetaEditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.ThetaEditField.Value = 90;
            app.alphaEditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.alphaEditField.Value = 1;
            app.alternateconfigurationCheckBox = uicheckbox(app.InputsvariablePanel);
            app.alternateconfigurationCheckBox.ValueChangedFcn = createCallbackFcn(app, @alternateconfigurationCheckBoxValueChanged, true);
            app.alternateconfigurationCheckBox.Text = 'alternate configuration';
            app.DropDown_alpha = uidropdown(app.InputsvariablePanel);
            app.DropDown_alpha.Items = {'Alpha 2', 'Alpha 3', 'Alpha 4'};
            app.DropDown_alpha.ValueChangedFcn = createCallbackFcn(app, @DropDown_alphaValueChanged, true);
            app.DropDown_alpha.Value = 'Alpha 2';
            app.omegaEditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.omegaEditField.ValueChangedFcn = createCallbackFcn(app, @omegaEditFieldValueChanged, true);
            app.omegaEditField.Value = 1;
            app.DropDown_omega = uidropdown(app.InputsvariablePanel);
            app.DropDown_omega.Items = {'Omega 2', 'Omega 3', 'Omega 4'};
            app.DropDown_omega.ValueChangedFcn = createCallbackFcn(app, @DropDown_omegaValueChanged, true);
            app.DropDown_omega.Value = 'Omega 2';
            app.DropDown_theta = uidropdown(app.InputsvariablePanel);
            app.DropDown_theta.Items = {'Theta 2', 'Theta 3', 'Theta 4'};
            app.DropDown_theta.ValueChangedFcn = createCallbackFcn(app, @DropDown_thetaValueChanged, true);
            app.DropDown_theta.Value = 'Theta 2';
            app.mLabel = uilabel(app.InputsvariablePanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.InputsvariablePanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.InputsvariablePanel);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.InputsvariablePanel);
            app.mLabel_4.Text = 'm';
            app.degLabel_4 = uilabel(app.InputsvariablePanel);
            app.degLabel_4.Text = 'deg';
            app.degLabel_5 = uilabel(app.InputsvariablePanel);
            app.degLabel_5.Text = 'deg';
            app.radsLabel_6 = uilabel(app.InputsvariablePanel);
            app.radsLabel_6.Text = 'rad/s';
            app.rads2Label_4 = uilabel(app.InputsvariablePanel);
            app.rads2Label_4.Text = 'rad/s^2';
            app.mLabel_9 = uilabel(app.InputsvariablePanel);
            app.mLabel_9.Text = 'm';
            app.R5EditFieldLabel = uilabel(app.InputsvariablePanel);
            app.R5EditFieldLabel.Text = 'R5';
            app.R5EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.R5EditField.ValueChangedFcn = createCallbackFcn(app, @R5EditFieldValueChanged, true);
            app.R5EditField.Value = 0.5;
            app.Theta1EditField_3 = uieditfield(app.InputsvariablePanel, 'numeric');
            app.Theta1EditField_3.Value = -180;
            app.Theta1EditFieldLabel_2 = uilabel(app.InputsvariablePanel);
            app.Theta1EditFieldLabel_2.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.InputsvariablePanel, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.degLabel_10 = uilabel(app.InputsvariablePanel);
            app.degLabel_10.Text = 'deg';
            app.CalculateButton = uibutton(app.InputsvariablePanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.analysismotionTab = uitab(app.TabGroup);
            app.analysismotionTab.Title = 'analysis motion';
            app.UIAxes2 = uiaxes(app.analysismotionTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.InputsvariablePanel_2 = uipanel(app.analysismotionTab);
            app.InputsvariablePanel_2.Title = 'Inputs variable';
            app.R1EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.R1EditField_2Label.Text = 'R1';
            app.R1EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.R1EditField_2.ValueChangedFcn = createCallbackFcn(app, @R1EditField_2ValueChanged, true);
            app.R1EditField_2.Value = 1.5;
            app.R2EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.R2EditField_2Label.Text = 'R2';
            app.R2EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.R2EditField_2.ValueChangedFcn = createCallbackFcn(app, @R2EditField_2ValueChanged, true);
            app.R2EditField_2.Value = 1;
            app.R3EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.R3EditField_2Label.Text = 'R3';
            app.R3EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.R3EditField_2.ValueChangedFcn = createCallbackFcn(app, @R3EditField_2ValueChanged, true);
            app.R3EditField_2.Value = 2;
            app.R4EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.R4EditField_2Label.Text = 'R4';
            app.R4EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.R4EditField_2.ValueChangedFcn = createCallbackFcn(app, @R4EditField_2ValueChanged, true);
            app.R4EditField_2.Value = 1;
            app.Theta1EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.Theta1EditField_2Label.Text = 'Theta1';
            app.Theta1EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.Theta1EditField_2.ValueChangedFcn = createCallbackFcn(app, @Theta1EditField_2ValueChanged, true);
            app.mLabel_5 = uilabel(app.InputsvariablePanel_2);
            app.mLabel_5.Text = 'm';
            app.mLabel_6 = uilabel(app.InputsvariablePanel_2);
            app.mLabel_6.Text = 'm';
            app.mLabel_7 = uilabel(app.InputsvariablePanel_2);
            app.mLabel_7.Text = 'm';
            app.mLabel_8 = uilabel(app.InputsvariablePanel_2);
            app.mLabel_8.Text = 'm';
            app.degLabel_9 = uilabel(app.InputsvariablePanel_2);
            app.degLabel_9.Text = 'deg';
            app.calculateButton_fast = uibutton(app.InputsvariablePanel_2, 'push');
            app.calculateButton_fast.ButtonPushedFcn = createCallbackFcn(app, @calculateButton_fastPushed, true);
            app.calculateButton_fast.Text = 'calculate';
            app.mLabel_10 = uilabel(app.InputsvariablePanel_2);
            app.mLabel_10.Text = 'm';
            app.R5EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.R5EditField_2Label.Text = 'R5';
            app.R5EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.R5EditField_2.ValueChangedFcn = createCallbackFcn(app, @R5EditField_2ValueChanged, true);
            app.R5EditField_2.Value = 1;
            app.degLabel_11 = uilabel(app.InputsvariablePanel_2);
            app.degLabel_11.Text = 'deg';
            app.Theta5EditField_2Label = uilabel(app.InputsvariablePanel_2);
            app.Theta5EditField_2Label.Text = 'Theta5';
            app.Theta5EditField_2 = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.Theta5EditField_2.ValueChangedFcn = createCallbackFcn(app, @Theta5EditField_2ValueChanged, true);
            app.Theta5EditField_2.Value = 20;
            app.radsLabel_12 = uilabel(app.InputsvariablePanel_2);
            app.radsLabel_12.Text = 'rad/s';
            app.radsLabel_13 = uilabel(app.InputsvariablePanel_2);
            app.radsLabel_13.Text = 'rad/s';
            app.omega2EditFieldLabel = uilabel(app.InputsvariablePanel_2);
            app.omega2EditFieldLabel.Text = 'omega2';
            app.omega2EditField = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.omega2EditField.ValueChangedFcn = createCallbackFcn(app, @omega2EditFieldValueChanged, true);
            app.omega2EditField.Value = 1;
            app.alpha2EditFieldLabel = uilabel(app.InputsvariablePanel_2);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.InputsvariablePanel_2, 'numeric');
            app.alpha2EditField.ValueChangedFcn = createCallbackFcn(app, @alpha2EditFieldValueChanged, true);
            app.alpha2EditField.Value = 1;
            app.Slider = uislider(app.analysismotionTab);
            app.Slider.Limits = [0 360];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 40;
            app.plotDropDown_2 = uidropdown(app.analysismotionTab);
            app.plotDropDown_2.Items = {'position', 'vlocity', 'acceleration'};
            app.plotDropDown_2.ValueChangedFcn = createCallbackFcn(app, @plotDropDown_2ValueChanged, true);
            app.plotDropDown_2.Value = 'position';
            app.Theta2Label_2 = uilabel(app.analysismotionTab);
            app.Theta2Label_2.Text = 'Theta 2 : ';
            app.Label = uilabel(app.analysismotionTab);
            app.Label.Text = '40';
            app.degLabel_12 = uilabel(app.analysismotionTab);
            app.degLabel_12.Text = 'deg';
            app.aboutusTab = uitab(app.TabGroup);
            app.aboutusTab.Title = 'about us';
            app.Image = uiimage(app.aboutusTab);
            app.Image.ImageSource = '765900ba9-article-200807-github-gitguardbody-text.jpg';
            app.Hyperlink = uihyperlink(app.aboutusTab);
            app.Hyperlink.URL = 'https://github.com/msajad79/Four_Bar_Linkage';
            app.Hyperlink.Text = 'https://github.com/msajad79/Four_Bar_Linkage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FoueBar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end